classdef SEND_to_PICO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TXLamp                    matlab.ui.control.Lamp
        TXLampLabel               matlab.ui.control.Label
        ConectarBLTLamp           matlab.ui.control.Lamp
        ConectarBLTLampLabel      matlab.ui.control.Label
        RUTINAPARAENVIARUNDATOALAPICOPICO2Label  matlab.ui.control.Label
        DATOEditField             matlab.ui.control.NumericEditField
        DATOEditFieldLabel        matlab.ui.control.Label
        PUERTO_BLTEditField       matlab.ui.control.EditField
        PUERTO_BLTEditFieldLabel  matlab.ui.control.Label
        SALIRButton               matlab.ui.control.Button
        ENVIARButton              matlab.ui.control.Button
        CONECTARButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
        %                                                              %
        %            UNIVERSIDAD NACIONAL AUTÓNOMA DE MÉXICO           %
        %               DEPARTAMENTO DE CONTROL Y ROBÓTICA             %
        %                                                              %
        %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
        %                                                              %
        %        M. EN I. JOSÉ ANTONIO DE JESÚS ARREDONDO GARZA        %
        %                    EMAIL: jarredon@unam.mx                   %
        %                           AÑO 2025                           %
        %                                                              %
        %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
        %                                                              %
        %        DECLARACIÓN DE VARIABLES USADAS EN EL PROGRAMA        %
        %                                                              %
        %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%

        My_Port;                      % Variable para contener el puerto
                                      % bluetooth.
        CONECTADO;                    % Bandera para indicar conexión.
        DATA;                         % Variable para contener el dato
                                      % a transmitir.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            %                                          %
            %    CONDICIONES INICIALES DEL PROGRAMA    %
            %                                          %
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            app.ConectarBLTLamp.Color = "Red";   % Pon el LED de conexión
                                                 % en color rojo.
            app.TXLamp.Color = "Red";            % LED de transmisión en
                                                 % color rojo.
            app.CONECTADO = 0;                   % Bandera de conexión en
                                                 % cero.
        end

        % Button pushed function: ENVIARButton
        function ENVIARButtonPushed(app, event)
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            %                                                           %
            %    RUTINA PARA TRANSMISIÓN DEL DATO DEL CAMPO NUMÉRICO    %
            %                                                           %
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            if app.CONECTADO == 1
                app.TXLamp.Color = "Green";          % Pon en verde el
                                                     % inidicador Tx.
                app.DATA = app.DATOEditField.Value;  % Obten el dato del 
                                                     % campo numérico.

                %**********************************%
                %  Conversión de "single" (float)  %
                %  a un arreglo de 32 bits.        %
                %**********************************%
                DATA32 = sscanf(num2hex(single(app.DATA)), '%2x'); 

                %*************************************************%                                                    
                % Reordena los Datos para que estén en el arreglo %
                % "LITTLE ENDIAN", pero primeraro agrega caracter %
                % guía a la secuencia:                            %
                %*************************************************%
                ENDIAN(1) = 85;          % Caracter guía "U".
                ENDIAN(2) = DATA32(4);
                ENDIAN(3) = DATA32(3);
                ENDIAN(4) = DATA32(2);
                ENDIAN(5) = DATA32(1);


                write(app.My_Port, ENDIAN, "uint8");  % Realiza Transmisión
                                                      % de bytes ENDIAN.
                pause(1)
                app.TXLamp.Color = "Red";             % Pon en rojo el
                                                      % inidicador Tx.
            else
                warndlg('¡NO ESTÁ CONECTADO!', 'MENSAJE')
            end
        end

        % Button pushed function: SALIRButton
        function SALIRButtonPushed(app, event)
            close(app.UIFigure)
            clear all;
        end

        % Button pushed function: CONECTARButton
        function CONECTARButtonPushed(app, event)
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            %                                               %
            %    RUTINA DE CONEXIÓN DEL PUERTO BLUETOOTH    %
            %                                               %
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            if app.CONECTADO == 1
                warndlg('¡Ya está conectador el Bluetooth!', 'MENSAJE')

            else
                NOMBRE = app.PUERTO_BLTEditField.Value; % Obten el nombre
                                                        % del puerto BLT.
                app.My_Port = bluetooth(NOMBRE,1);      % Conecta el puerto
                                                        % bluetooth.
                app.ConectarBLTLamp.Color = "Green";    % LED indicador en
                                                        % color Verde.
                app.CONECTADO = 1;                      % Pon la bandera de
                                                        % conexión = 1.
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CONECTARButton = uibutton(app.UIFigure, 'push');
            app.CONECTARButton.ButtonPushedFcn = createCallbackFcn(app, @CONECTARButtonPushed, true);
            app.CONECTARButton.Text = 'CONECTAR';
            app.ENVIARButton = uibutton(app.UIFigure, 'push');
            app.ENVIARButton.ButtonPushedFcn = createCallbackFcn(app, @ENVIARButtonPushed, true);
            app.ENVIARButton.Text = 'ENVIAR';
            app.SALIRButton = uibutton(app.UIFigure, 'push');
            app.SALIRButton.ButtonPushedFcn = createCallbackFcn(app, @SALIRButtonPushed, true);
            app.SALIRButton.Text = 'SALIR';
            app.PUERTO_BLTEditFieldLabel = uilabel(app.UIFigure);
            app.PUERTO_BLTEditFieldLabel.Text = 'PUERTO_BLT:';
            app.PUERTO_BLTEditField = uieditfield(app.UIFigure, 'text');
            app.PUERTO_BLTEditField.Value = 'MY_RP2';
            app.DATOEditFieldLabel = uilabel(app.UIFigure);
            app.DATOEditFieldLabel.Text = 'DATO:';
            app.DATOEditField = uieditfield(app.UIFigure, 'numeric');
            app.RUTINAPARAENVIARUNDATOALAPICOPICO2Label = uilabel(app.UIFigure);
            app.RUTINAPARAENVIARUNDATOALAPICOPICO2Label.Text = 'RUTINA PARA ENVIAR UN DATO A LA PICO / PICO2';
            app.ConectarBLTLampLabel = uilabel(app.UIFigure);
            app.ConectarBLTLampLabel.Text = 'Conectar BLT';
            app.ConectarBLTLamp = uilamp(app.UIFigure);
            app.TXLampLabel = uilabel(app.UIFigure);
            app.TXLampLabel.Text = 'TX:';
            app.TXLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEND_to_PICO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end