classdef VOLTMETER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        sedebepulsarMEDIRdenuevoLabel  matlab.ui.control.Label
        vecesporloqueparacontinuarLabel  matlab.ui.control.Label
        NotaelciclodemedicinserepiteLabel  matlab.ui.control.Label
        CentigradosLabel               matlab.ui.control.Label
        VoltiosLabel_3                 matlab.ui.control.Label
        VoltiosLabel_2                 matlab.ui.control.Label
        VoltiosLabel                   matlab.ui.control.Label
        MEDIDORDEVOLTIOSTEMPERATURACONPICOPICO2Label  matlab.ui.control.Label
        MidiendoLamp                   matlab.ui.control.Lamp
        MidiendoLampLabel              matlab.ui.control.Label
        ConectadoLamp                  matlab.ui.control.Lamp
        ConectadoLampLabel             matlab.ui.control.Label
        SALIRButton                    matlab.ui.control.Button
        MEDIRButton                    matlab.ui.control.Button
        CONECTARButton                 matlab.ui.control.Button
        PuertoEditField                matlab.ui.control.EditField
        PuertoEditFieldLabel           matlab.ui.control.Label
        TemperaturaEditField           matlab.ui.control.NumericEditField
        TemperaturaEditFieldLabel      matlab.ui.control.Label
        CanalAN2EditField              matlab.ui.control.NumericEditField
        CanalAN2EditFieldLabel         matlab.ui.control.Label
        CanalAN1EditField              matlab.ui.control.NumericEditField
        CanalAN1EditFieldLabel         matlab.ui.control.Label
        CanalAN0EditField              matlab.ui.control.NumericEditField
        CanalAN0Label                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
        %                                                        %
        %        UNIVERSIDAD NACIONAL AUTÓNOMA DE MÉXICO         %
        %                 FACULTAD DE INGENIERÍA                 %
        %          DEPARTAMENTO DE CONTROL Y ROBÓTICA            %
        %                                                        %
        %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
        %                                                        %
        %       M.I. JOSÉ ANTONIO DE JESÚS ARREDONDO GARZA       %
        %                  EMAIL: jarredon@unam.mx               %
        %                         AÑO 2025                       %
        %                                                        %
        %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX% 
        %                                                        %
        %  INICIALIZACIÓN DE LA VARIABLES USADAS EN EL PROGRAMA  %
        %                                                        %
        %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%

        NOMBRE;                     % Variable que contendrá el nombre del
                                    % puerto bluetooth.
        PUERTO;                     % Variable que contendrá el puerto
                                    % "bluetooth" a usar.
        CONECTADO;                  % Bandera para indicar si el bluetooth
                                    % está conectado.
        AN0;                        % Variable que contendrá la lectura
                                    % del canal AN0.
        AN1;                        % Variable que contendrá la lectura
                                    % del canal AN1.    
        AN2;                        % Variable que contendrá la lectura
                                    % del canal AN0.
        GRADOS;                     % Variable que contendrá los grados
                                    % centigrados medidos.
        SIZE_DATA;                  % Variable que contendrá los datos
                                    % recibidos por el puerto bluetooth.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            %                                                        %
            %  CONDICIONES DE INICIO DEL PROGRAMA, QUE EN ESTE CASO  %
            %  SOLO SE ESTÁN PONIENDO LOS LEDs INICADORES EN ROJO Y  %
            %  EL LETRERO DE ADVERTENCIA SOBRE EL DISPOSITIVO        %
            %  BLUETOOTH.                                            %
            %                                                        %
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            app.ConectadoLamp.Color = "Red";    % Pon el Led de conexión
                                                % bluetooth en rojo.
            app.MidiendoLamp.Color  = "Red";    % Pon el Led de medición
                                                % en color rojo.
            app.CONECTADO           = 0;        % Pon bandera "CONECTADO"
                                                % en cero.
        end

        % Button pushed function: SALIRButton
        function SALIRButtonPushed(app, event)
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            %                                                      %
            %  CÓDIGO PARA SALIR DE LA APLICACIÓN DEL BOTÓN SALIR  %
            %                                                      %
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%

            close(app.UIFigure)
            clear all;
        end

        % Button pushed function: CONECTARButton
        function CONECTARButtonPushed(app, event)

            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            %                                                        %
            %  REALIZA CONEXIÓN DE PUERTO BLUETOOTH E INDICA DE QUE  %
            %  TAMAÑO SON LOS DATOS A RECIBIR, PON LA BANDERA QUE    %
            %  INIDCA QUE ESTÁ CONECTADO Y PON EL VERDE EL LED DE    %
            %  "CONECTADO".                                          %
            %                                                        %
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%

            app.SIZE_DATA  = 16;           % Se recibirán 4 números en
                                           % formato "float" (en MATLAB 
                                           % se les llama "single"). 
                                           % Estos se reciben en formato
                                           % "little ENDIAN" y el programa
                                           % los convertirá a "single".

           if app.CONECTADO == 1
               
               %*****************************************%
               %  SI YA ESTÁ CONECTADO ENVÍA UN MENSAJE  %
               %*****************************************%
                warndlg(['Ya está conectado !'],  'MENSAJE')

           else
               %********************************%
               %  CONEXIÓN DE PUERTO BLUETOOTH  %
               %********************************%
               app.NOMBRE = app.PuertoEditField.Value;  % Obten el nombre
                                                        % del puerto BLT.
               app.PUERTO = bluetooth(app.NOMBRE, 1);   % Conecta puerto;
               app.ConectadoLamp.Color = "Green";       % Pon LED en verde.
               app.CONECTADO = 1;                       % Pon bandera de
                                                        % conectado.
           end
        end

        % Button pushed function: MEDIRButton
        function MEDIRButtonPushed(app, event)
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            %                                                             %
            %  EL CÓDIGO DEL BOTÓN PRIMERO DETECTA SI BLE ESTÁ CONECTADO  %
            %  Y SI ES EL CASO, TRASNMITE EL CARACTER DE SINCRONÍA "U"    %
            %  PARA QUE LA RASPBERRY PI PICO/PICO2 TRANSMITA TODAS SUS    %
            %  LECTURAS DEL CONVERIDOR A/D Y CREA UNA PAUSA DE 0.125 SEG  %
            %  ENTRE LAS SECUENCIAS DE LECTURA.                           %
            %                                                             %
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            if app.CONECTADO == 1

                for i = 1:100
                    app.MidiendoLamp.Color  = "Green";  % Pon LED en verde.
                    Syncro = 85;            
                    write(app.PUERTO, Syncro, "uint8"); % Transmite el
                                                        % caracter "U" de
                                                        % sincronía que es
                                                        % el 85.
                    pause(0.1)                          % Pausa 0.1 Seg.

                    %****************************************************%
                    % Lee el Puerto BLT y convierte los datos a "single" %
                    % (que es un float).                                 %
                    %****************************************************%
                    % NOTA IMPORTANTE: se están recibiendo 16 bytes,     %
                    % pero como se están conviertiendo a "single", se    %
                    % reciben 4 "singles", por eso se pone SIZE_DATA     %
                    % dividido entre 4.                                  %
                    %****************************************************%
                    DATOS = read(app.PUERTO,app.SIZE_DATA/4,"single");
                    flush(app.PUERTO)

                    %******************************%
                    %  SEPARA LOS DATOS OBTENIDOS  %
                    %******************************%
                    app.AN0    = DATOS(1);
                    app.AN1    = DATOS(2);
                    app.AN2    = DATOS(3);
                    app.GRADOS = DATOS(4);

                    %*****************************************%
                    %  ESCRIBE A LOS CAMPOS DE VISUALIZACIÓN  %
                    %*****************************************%
                    app.CanalAN0EditField.Value    = app.AN0;
                    app.CanalAN1EditField.Value    = app.AN1;
                    app.CanalAN2EditField.Value    = app.AN2;
                    app.TemperaturaEditField.Value = app.GRADOS;
                end
                app.MidiendoLamp.Color  = "Yellow";  % Pon LED en Amarillo.
                pause(1)                             % Retardo 1 Segundo.
                app.MidiendoLamp.Color  = "Red";     % Pon LED en rojo. 
            else

                %*********************************************&
                %  SI NO ESTÁ HABILITADO EL MÓDULO BLUETOOTH  %
                %  ENVÍA UN MENSAJE.                          %
                %*********************************************%

                warndlg('EL MÓDULO BLUETOOTH NO ESTÁ CONECTADO !', ...
                         'MENSAJE')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CanalAN0Label = uilabel(app.UIFigure);
            app.CanalAN0Label.Text = 'Canal AN0:';
            app.CanalAN0EditField = uieditfield(app.UIFigure, 'numeric');
            app.CanalAN1EditFieldLabel = uilabel(app.UIFigure);
            app.CanalAN1EditFieldLabel.Text = 'Canal AN1:';
            app.CanalAN1EditField = uieditfield(app.UIFigure, 'numeric');
            app.CanalAN2EditFieldLabel = uilabel(app.UIFigure);
            app.CanalAN2EditFieldLabel.Text = 'Canal AN2:';
            app.CanalAN2EditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperaturaEditFieldLabel = uilabel(app.UIFigure);
            app.TemperaturaEditFieldLabel.Text = 'Temperatura:';
            app.TemperaturaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PuertoEditFieldLabel = uilabel(app.UIFigure);
            app.PuertoEditFieldLabel.Text = 'Puerto';
            app.PuertoEditField = uieditfield(app.UIFigure, 'text');
            app.PuertoEditField.Value = 'MY_RP2';
            app.CONECTARButton = uibutton(app.UIFigure, 'push');
            app.CONECTARButton.ButtonPushedFcn = createCallbackFcn(app, @CONECTARButtonPushed, true);
            app.CONECTARButton.Text = 'CONECTAR';
            app.MEDIRButton = uibutton(app.UIFigure, 'push');
            app.MEDIRButton.ButtonPushedFcn = createCallbackFcn(app, @MEDIRButtonPushed, true);
            app.MEDIRButton.Text = 'MEDIR';
            app.SALIRButton = uibutton(app.UIFigure, 'push');
            app.SALIRButton.ButtonPushedFcn = createCallbackFcn(app, @SALIRButtonPushed, true);
            app.SALIRButton.Text = 'SALIR';
            app.ConectadoLampLabel = uilabel(app.UIFigure);
            app.ConectadoLampLabel.Text = 'Conectado';
            app.ConectadoLamp = uilamp(app.UIFigure);
            app.MidiendoLampLabel = uilabel(app.UIFigure);
            app.MidiendoLampLabel.Text = 'Midiendo';
            app.MidiendoLamp = uilamp(app.UIFigure);
            app.MEDIDORDEVOLTIOSTEMPERATURACONPICOPICO2Label = uilabel(app.UIFigure);
            app.MEDIDORDEVOLTIOSTEMPERATURACONPICOPICO2Label.Text = 'MEDIDOR DE VOLTIOS - TEMPERATURA CON PICO / PICO2';
            app.VoltiosLabel = uilabel(app.UIFigure);
            app.VoltiosLabel.Text = '[ Voltios ]';
            app.VoltiosLabel_2 = uilabel(app.UIFigure);
            app.VoltiosLabel_2.Text = '[ Voltios ]';
            app.VoltiosLabel_3 = uilabel(app.UIFigure);
            app.VoltiosLabel_3.Text = '[ Voltios ]';
            app.CentigradosLabel = uilabel(app.UIFigure);
            app.CentigradosLabel.Text = '[ Centigrados ]';
            app.NotaelciclodemedicinserepiteLabel = uilabel(app.UIFigure);
            app.NotaelciclodemedicinserepiteLabel.Text = 'Nota: el ciclo de medición se repite';
            app.vecesporloqueparacontinuarLabel = uilabel(app.UIFigure);
            app.vecesporloqueparacontinuarLabel.Text = '100 veces, por lo que, para continuar';
            app.sedebepulsarMEDIRdenuevoLabel = uilabel(app.UIFigure);
            app.sedebepulsarMEDIRdenuevoLabel.Text = 'se debe pulsar MEDIR de nuevo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VOLTMETER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end